classdef plasma_frequency_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        UIAxes                       matlab.ui.control.UIAxes
        epsilon_1EditFieldLabel      matlab.ui.control.Label
        epsilon1EditField            matlab.ui.control.NumericEditField
        electrondensityEditField_2Label  matlab.ui.control.Label
        electrondensityEditField     matlab.ui.control.NumericEditField
        effectivemassEditFieldLabel  matlab.ui.control.Label
        effectivemassEditField       matlab.ui.control.NumericEditField
    end

    
    properties (Access = public)
        n_e;
        c;
        q;
        m_e
        epsilon1;
        epsilon2;
        m_0;
    end
    
    methods (Access = private)
        
        function [] = new_plot(app)
            cla(app.UIAxes);
            
            omega_p = sqrt((app.n_e*app.q^2)/(app.epsilon1*app.m_0*app.m_e)); %plasma frequency (ionosphere)
            omega_sp = omega_p/sqrt(app.epsilon1+1);
            omega = linspace(0, omega_p, 50);
            app.epsilon2 = 1 - omega_p^2./omega.^2; %permittivy material 2 (metal) this is a function of frequency
            beta = omega/app.c.*sqrt((app.epsilon1*app.epsilon2)./(app.epsilon1+app.epsilon2));
            index = find(omega > omega_sp, 1); %find index of omega > omega_sp
            plot (app.UIAxes, real(beta(1:index-1)), omega(1:index-1)); %plot omega < omega_sp
            text (app.UIAxes, 0, omega(index-1), "$\omega_{sp}$", 'Interpreter', 'latex', 'HorizontalAlignment', 'left', 'VerticalAlignment', 'bottom', 'HorizontalAlignment', 'right','FontSize',14);
            hold (app.UIAxes, "on");
            plot (app.UIAxes, [0 max(real(beta))],[omega_sp omega_sp]); %line at omega_sp
            hold (app.UIAxes, "on");
            plot (app.UIAxes, real(beta(index:end)), omega(index:end)); %plot omega_sp < omega < omega_p
            hold (app.UIAxes, "on");
            beta = linspace (0, max(real(beta))); %redefining beta for omega > omega_p
            omega_plasma = sqrt(omega_p^2 + app.c^2*beta.^2); % plot omega > omega_p
            
            plot (app.UIAxes, real(beta), omega_plasma); % plot omega > omega_p
            text (app.UIAxes, 0, omega_p, "$\omega_p$", 'Interpreter', 'latex', 'HorizontalAlignment', 'left', 'VerticalAlignment', 'bottom', 'HorizontalAlignment', 'right','FontSize',14);
            hold (app.UIAxes, "on");
            plot (app.UIAxes, real(beta), app.c*real(beta));
            text (app.UIAxes, max(real(beta)), app.c*max(real(beta)), '$\leftarrow \omega = c \cdot \beta$', 'Interpreter', 'latex','FontSize',14);            
            legend (app.UIAxes, "$\beta = \frac{\omega}{c} \sqrt{\frac{\epsilon_{r1} \epsilon_{r2}}{\epsilon_{r1}+\epsilon_{r2}}}$", "$\omega = \omega_{sp}$", "Prohibited region", "$\omega^2 = \omega_p^2 + c^2 \cdot \beta^2$", "$\omega = c \cdot \beta$",'Interpreter', 'latex', 'Location', 'eastoutside', 'FontSize', 14);
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % costants
            app.c = 3*10^8; %light velocity
            app.q = 1.602176634*10^(-19); %electric charge
            app.m_e = 9.1093837015*10^(-31); %electron mass
            %n_e = 9.65*10^9; %silicon electron density
            app.n_e = 10^23; %conductor electron density
            app.electrondensityEditField.Value = app.n_e;
            
            app.epsilon1 = 1; %permittivy material 1 (dielectric), vacuum permittivy
            app.epsilon1EditField.Value = app.epsilon1;
            app.m_0 = 1;
            app.effectivemassEditField.Value = app.m_0;
            
            new_plot(app);
            
            xlabel (app.UIAxes, "$\beta~\left[\frac{1}{m}\right]$", 'Interpreter', 'latex', 'FontSize', 14);
            ylabel (app.UIAxes, "$\omega~\left[\frac{rad}{s}\right]$", 'Interpreter', 'latex', 'FontSize', 14);
            title (app.UIAxes, "$\omega(\beta)$", 'Interpreter','latex');
        end

        % Value changed function: epsilon1EditField
        function epsilon1EditFieldValueChanged(app, event)
            app.epsilon1 = app.epsilon1EditField.Value;
            
            new_plot(app);
        end

        % Value changed function: electrondensityEditField
        function electrondensityEditFieldValueChanged(app, event)
            app.n_e = app.electrondensityEditField.Value;
           
            new_plot(app);
        end

        % Value changed function: effectivemassEditField
        function effectivemassEditFieldValueChanged(app, event)
            app.m_e = app.effectivemassEditField.Value;
            
            new_plot(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Energy dispersion relation')
            xlabel(app.UIAxes, 'beta')
            ylabel(app.UIAxes, 'omega')
            app.epsilon_1EditFieldLabel = uilabel(app.UIFigure);
            app.epsilon_1EditFieldLabel.Text = 'epsilon_1';
            app.epsilon1EditField = uieditfield(app.UIFigure, 'numeric');
            app.epsilon1EditField.ValueChangedFcn = createCallbackFcn(app, @epsilon1EditFieldValueChanged, true);
            app.epsilon1EditField.Value = 1;
            app.electrondensityEditField_2Label = uilabel(app.UIFigure);
            app.electrondensityEditField_2Label.Text = 'electron density';
            app.electrondensityEditField = uieditfield(app.UIFigure, 'numeric');
            app.electrondensityEditField.Limits = [0 Inf];
            app.electrondensityEditField.ValueChangedFcn = createCallbackFcn(app, @electrondensityEditFieldValueChanged, true);
            app.electrondensityEditField.Value = 1e+23;
            app.effectivemassEditFieldLabel = uilabel(app.UIFigure);
            app.effectivemassEditFieldLabel.Text = 'effective mass';
            app.effectivemassEditField = uieditfield(app.UIFigure, 'numeric');
            app.effectivemassEditField.Limits = [1e-07 Inf];
            app.effectivemassEditField.ValueChangedFcn = createCallbackFcn(app, @effectivemassEditFieldValueChanged, true);
            app.effectivemassEditField.Value = 1;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = plasma_frequency_app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end